classdef Sums < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        StartGraphingButton            matlab.ui.control.Button
        SubIntervalsSliderLabel        matlab.ui.control.Label
        SubIntervalsSlider             matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        aboundEditFieldLabel           matlab.ui.control.Label
        aboundEditField                matlab.ui.control.NumericEditField
        bboundEditFieldLabel           matlab.ui.control.Label
        bboundEditField                matlab.ui.control.NumericEditField
        SumTypeButtonGroup             matlab.ui.container.ButtonGroup
        LeftEndpointButton             matlab.ui.control.RadioButton
        MidpointButton                 matlab.ui.control.RadioButton
        RightEndpointButton            matlab.ui.control.RadioButton
        TrapezoidalButton              matlab.ui.control.RadioButton
        ParabolicSimpsonsRuleButton    matlab.ui.control.RadioButton
        FunctionInputfxEditFieldLabel  matlab.ui.control.Label
        FunctionInputfxEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        lowerBound = 0 % a bound of reimann sum
        upperBound = 0 % b bound of reimann sum
        n = 0
        selections = zeros(5)
        expression = 0
        validFunc = 0
        mode = 0 % will store index of selected radio button
    end


    methods (Access = private)

        % Selection changed function: SumTypeButtonGroup
        function SumTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SumTypeButtonGroup.SelectedObject;
            app.selections = [app.LeftEndpointButton.Value, app.MidpointButton.Value, app.RightEndpointButton.Value, app.TrapezoidalButton.Value, app.ParabolicSimpsonsRuleButton.Value]
        end

        % Value changed function: SubIntervalsSlider
        function SubIntervalsSliderValueChanged(app, event)
            value = app.SubIntervalsSlider.Value;
            app.n = floor(value)
            app.Label.Text = num2str(app.n)
        end

        % Value changed function: aboundEditField
        function aboundEditFieldValueChanged(app, event)
            value = app.aboundEditField.Value;
            app.lowerBound = value
        end

        % Value changed function: bboundEditField
        function bboundEditFieldValueChanged(app, event)
            value = app.bboundEditField.Value;
            app.upperBound = value
        end

        % Button pushed function: StartGraphingButton
        function StartGraphingButtonPushed(app, event)

            if app.validFunc == 0
                uialert(app.UIFigure,'Invalid Function Expression', 'Invalid Function');
            else
                for i=1:app.selections
                    if app.selections(i) == 1
                        app.mode = app.selections(i)
                    end
                end
            end
        end

        % Value changed function: FunctionInputfxEditField
        function FunctionInputfxEditFieldValueChanged(app, event)
            value = app.FunctionInputfxEditField.Value;
            try
                app.expression = str2sym(value)
                app.validFunc = 1
                x = 0
                eval(value)
                %exists(app.expression)
                syms x
                app.Label.Text = num2str(double(subs(app.expression, x, 3)))
            catch
                app.validFunc = 0
                uialert(app.UIFigure,'Invalid Function Expression', 'Invalid Function');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartGraphingButton = uibutton(app.UIFigure, 'push');
            app.StartGraphingButton.ButtonPushedFcn = createCallbackFcn(app, @StartGraphingButtonPushed, true);
            app.StartGraphingButton.Text = 'Start Graphing';
            app.SubIntervalsSliderLabel = uilabel(app.UIFigure);
            app.SubIntervalsSliderLabel.Text = 'Sub Intervals';
            app.SubIntervalsSlider = uislider(app.UIFigure);
            app.SubIntervalsSlider.Limits = [0 50];
            app.SubIntervalsSlider.ValueChangedFcn = createCallbackFcn(app, @SubIntervalsSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.aboundEditFieldLabel = uilabel(app.UIFigure);
            app.aboundEditFieldLabel.Text = 'a bound';
            app.aboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.aboundEditField.RoundFractionalValues = 'on';
            app.aboundEditField.ValueChangedFcn = createCallbackFcn(app, @aboundEditFieldValueChanged, true);
            app.bboundEditFieldLabel = uilabel(app.UIFigure);
            app.bboundEditFieldLabel.Text = 'b bound';
            app.bboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.bboundEditField.RoundFractionalValues = 'on';
            app.bboundEditField.ValueChangedFcn = createCallbackFcn(app, @bboundEditFieldValueChanged, true);
            app.SumTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SumTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SumTypeButtonGroupSelectionChanged, true);
            app.SumTypeButtonGroup.Title = 'Sum Type';
            app.LeftEndpointButton = uiradiobutton(app.SumTypeButtonGroup);
            app.LeftEndpointButton.Text = 'Left Endpoint';
            app.LeftEndpointButton.Value = true;
            app.MidpointButton = uiradiobutton(app.SumTypeButtonGroup);
            app.MidpointButton.Text = 'Midpoint';
            app.RightEndpointButton = uiradiobutton(app.SumTypeButtonGroup);
            app.RightEndpointButton.Text = 'Right Endpoint';
            app.TrapezoidalButton = uiradiobutton(app.SumTypeButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.ParabolicSimpsonsRuleButton = uiradiobutton(app.SumTypeButtonGroup);
            app.ParabolicSimpsonsRuleButton.Text = 'Parabolic (Simpson''s Rule)';
            app.FunctionInputfxEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionInputfxEditFieldLabel.Text = 'Function Input f(x) = ';
            app.FunctionInputfxEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionInputfxEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionInputfxEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sums

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end